classdef dtmf_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Button              matlab.ui.control.Button
        Button_2            matlab.ui.control.Button
        Button_3            matlab.ui.control.Button
        Button_4            matlab.ui.control.Button
        Button_5            matlab.ui.control.Button
        Button_6            matlab.ui.control.Button
        Button_7            matlab.ui.control.Button
        Button_8            matlab.ui.control.Button
        Button_9            matlab.ui.control.Button
        Button_10           matlab.ui.control.Button
        Button_11           matlab.ui.control.Button
        Button_12           matlab.ui.control.Button
        DTMFGeneratorLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        fs=8000 %sampling frequency
    end
    
    methods (Access = private)
        
        function y = func(~,f1,f2)
            Fs=8000; %sampling frequency
            t=0:1:4000; %0.5 seconds gap between action and reaction
            flow=sin(2*pi*(f1/(Fs)).*t); %lower frequency
            fhigh=sin(2*pi*(f2/(Fs)).*t); %higher frequency
            y=flow+fhigh; %DTMF frequency


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
        y1=func(app,697,1209);
        sound(y1,app.fs);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
             y2=func(app,697,1366);
             sound(y2,app.fs);
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
             y3=func(app,697,1477);
             sound(y3,app.fs);
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
             y4=func(app,770,1209);
             sound(y4,app.fs);
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
             y5=func(app,770,1366);
             sound(y5,app.fs);
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
             y6=func(app,770,1477);
             sound(y6,app.fs);
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
             y7=func(app,852,1209);
             sound(y7,app.fs);
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
             y8=func(app,852,1366);
             sound(y8,app.fs);
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
             y9=func(app,852,1477);
             sound(y9,app.fs);
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
             y10=func(app,941,1209);
             sound(y10,app.fs);
        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
             y11=func(app,941,1366);
             sound(y11,app.fs);
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
             y12=func(app,941,1477);
             sound(y12,app.fs);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '1';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '7';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '4';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '5';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '8';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '9';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '6';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '3';
            app.Button_10 = uibutton(app.UIFigure, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '#';
            app.Button_11 = uibutton(app.UIFigure, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = '0';
            app.Button_12 = uibutton(app.UIFigure, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Text = '*';
            app.DTMFGeneratorLabel = uilabel(app.UIFigure);
            app.DTMFGeneratorLabel.Text = 'DTMF Generator';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dtmf_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end